classdef EPITIME_SimulationTool < matlab.apps.AppBase
    %EPITIME_SIMULATIONTOOL Age-of-infection epidemic model by NSFD scheme
    %   NSFD Method
    %   Unconditionally positive, monotonicity-preserving non standard
    %   finite difference (NSFD) scheme for the solution of the
    %   age-of-infection (AoI) epidemic model
    %     S'(t) = β S(t)φ(t)
    %     φ(t)  = φ₀(t) + β ∫₀ᵗ A(t-τ) S(τ)φ(τ) dτ
    %   where
    %   S(t): number of susceptibles
    %   φ(t): total infectivity
    %       
    %   Version:    1.0
    %   Date:       April, 2026
    %   Authors:    B. Buonomo, E. Messina, C. Panico, M. Pezzella,
    %               G. Zanghirati
    %   See:        https://arxiv.org/abs/2605.00067v1
    %   License:    GNU GPL v.3
    %   Repository: https://github.com/ghitan/EPITIME
    %      
    %   ==========================================================================
    %                                 
    %   AoI problem data:
    %   - S0       : Initial number of susceptibles S(0)     [Default: 990]
    %   - N        : Population Size                        [Default: 1000]
    %   - T        : Final time T (integration over [0,T])    [Default: 10]
    %   - h        : Time step size                          [Default: 0.1]
    %   - beta     : Effective contact rate               [Default: 0.0025]
    %   - A        : Infectivity kernel A(t)            [Default: exp(-3t)]
    %   - phi0     : Initial infectivity φ₀(t)        [Default: (N–S0)A(t)]
    %   - verbosity: 0 to suppress warnings, 1 to enable       [Default: 1]
    %                                 
    %   Output to Matlab's workspace:
    %   - t: Time vector on a uniform grid with spacing h, with the last
    %        point adjusted if T is not an integer multiple of h
    %   - Y: Solution array. Each column is [Sₙ; φₙ] ≈ [S(tₙ); φ(tₙ)]
    %   - P: (Optional) Structure with performance metrics:
    %        - elapsed_time: Total execution time (seconds) excluding
    %                        input checks
    %        - steps_number: Total number of time steps performed
    %        - flops_number: Estimated number of floating-point operations
    %                        not including kernel function evaluations,
    %                        for which the computational cost is not
    %                        assessed
    %        - Kern_number : Number of kernel function evaluations
    %        - memory_bytes: Estimated memory used by core variables
    %                        (bytes) 
    %                                
    %   ==========================================================================
    %   
    %   For further details see:
    %   E. Messina, M. Pezzella, A. Vecchio,
    %   "A non-standard numerical scheme for an age-of-infection epidemic
    %   model"
    %   Journal of Computational Dynamics, 2022, 9(2): 239–252.
    %   DOI: 10.3934/jcd.2021029
    %      
    %   ==========================================================================
    %                                 
    %   DISCLAIMER
    %                                 
    %   This program is free software: you can redistribute it and/or
    %   modify it under the terms of the GNU General Public License as
    %   published by the Free Software Foundation, either version 3 of the
    %   License, or (at your option) any later version.
    %                                 
    %   This program is distributed in the hope that it will be useful,but
    %   WITHOUT ANY WARRANTY; without even the implied warranty of
    %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.
    %   See the GNU General Public License for more details.
    %   
    %   You should have received a copy of the GNU General Public License
    %   along with this program.  If not, see
    %   <https://www.gnu.org/licenses/>.
    %   
    %   ==========================================================================
    %                                 

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_Title                    matlab.ui.control.Label
        Label_FootLine                 matlab.ui.control.Label
        Label_ScalarParametersSection  matlab.ui.control.Label
        N                              matlab.ui.control.NumericEditField
        Label_N                        matlab.ui.control.Label
        S0                             matlab.ui.control.NumericEditField
        Label_S0                       matlab.ui.control.Label
        T                              matlab.ui.control.NumericEditField
        Label_T                        matlab.ui.control.Label
        h                              matlab.ui.control.NumericEditField
        Label_h                        matlab.ui.control.Label
        beta0                          matlab.ui.control.NumericEditField
        Label_beta0                    matlab.ui.control.Label
        verbosity                      matlab.ui.control.NumericEditField
        Label_verbosity                matlab.ui.control.Label
        Label_FunctionHandlesSection   matlab.ui.control.Label
        Label_InstructionsFunctionHandles  matlab.ui.control.Label
        TextArea_A                     matlab.ui.control.TextArea
        TextArea_phi0                  matlab.ui.control.TextArea
        phi0tLabel                     matlab.ui.control.Label
        S0EditFieldLabel               matlab.ui.control.Label
        Button_RunSimulation           matlab.ui.control.Button
        Button_Reset                   matlab.ui.control.Button
        Label_EndOfRunMessage          matlab.ui.control.Label
        Label_SimulationsData          matlab.ui.control.Label
        Label_SimulationResults        matlab.ui.control.Label
        AtLabel                        matlab.ui.control.Label
        verbosityEditFieldLabel        matlab.ui.control.Label
        TEditFieldLabel                matlab.ui.control.Label
        hEditFieldLabel                matlab.ui.control.Label
        NEditFieldLabel                matlab.ui.control.Label
        beta_0Label                    matlab.ui.control.Label
        Axes_InfectivityKernel         matlab.ui.control.UIAxes
        Axes_phi0                      matlab.ui.control.UIAxes
        Axes_Susceptibles              matlab.ui.control.UIAxes
        Axes_MeanInfectivity           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        AoI_Problem = struct(); % Structure with problem inputs to the main function
        t_AoI = []; % Simulation time steps
        Y_AoI = []; % Computed solution at time steps
        P_AoI = []; % Performance indicators of the simulation
    end
    
    properties (Access = private)
        waitingBgColor = [0.47,0.67,0.19]; % green
        runningBgColor = [0.00,0.00,1.00]; % blue
        warningBgColor = [1.00,0.65,0.00]; % orange
        errorBgColor   = [1.00,0.00,0.00]; % red
        errorFlag      = 0; % Flag: 0 = no errors/warning, 1 = warning issued; 2 = error issued
        InfectKernPlotHandle   = [];
        phi0PlotHandle         = [];
        SusceptiblesPlotHandle = [];
        MeanInfectPlotHandle   = [];
        yDataZeroThreshold     = 1.0e-6; % To set the interval for plotting problem data: default 1.0E-6
        xxA                    = [];
        xxPhi0                 = [];
        % local variables to manage function definitions
        prefix     = 'app.AoI_Problem.';
        prefixLen  = 16; % = numel( app.prefix );
        modelParNames   = {'N', 'S0', 'T', 'h', 'beta0', 'A'};
        lookbehind = '(?<![a-zA-Z_])';   % regular expression conditions preceding params
        lookahead  = '(?![a-zA-Z0-9_])'; % regular expression conditions following params
    end
    
    methods (Access = private)
        
        function figHandle = plotSolution(app)
            % Susceptibles plot
            app.SusceptiblesPlotHandle = ...
                plot(app.Axes_Susceptibles, app.t_AoI, app.Y_AoI(1, :), ...
                     'Color', '#80B3FF', 'LineWidth', 2);  % Color: light blue
            % Mean infectivity plot
            app.MeanInfectPlotHandle = ...
                plot(app.Axes_MeanInfectivity, app.t_AoI, app.Y_AoI(2, :), ...
                     'Color', [0.8500 0.3250 0.0980], 'LineWidth', 2); % Color: dark orange
        end
        
        function resetRunButtonAndMsgLabel(app)
            app.Button_RunSimulation.BackgroundColor = app.waitingBgColor;
            app.Button_RunSimulation.Text = 'Run simulation';            
            app.Label_EndOfRunMessage.Text = '';
        end
        
        function rePlotSimulationData(app)
            xx = (0 : app.h.Value : app.T.Value)';
            xmaxA_ind    = find( app.AoI_Problem.A(xx)    >= app.yDataZeroThreshold, 1, 'last' );
            xmaxPhi0_ind = find( app.AoI_Problem.phi0(xx) >= app.yDataZeroThreshold, 1, 'last' );
            app.xxA    = xx(1 : xmaxA_ind);
            app.xxPhi0 = xx(1 : xmaxPhi0_ind);
            app.InfectKernPlotHandle = ...
                plot(app.Axes_InfectivityKernel, app.xxA, app.AoI_Problem.A( app.xxA ), ...
                     'b', 'LineWidth', 2);
            app.phi0PlotHandle = ...
                plot(app.Axes_phi0, app.xxPhi0, app.AoI_Problem.phi0( app.xxPhi0 ), ...
                     'Color', [0.47,0.67,0.19], 'LineWidth', 2);   % Color: olive green            
        end
        
        function results = setDefaults(app)
            % set field values defult
            app.N.Value             = 1000;
            app.S0.Value            = 990;
            app.T.Value             = 10;
            app.h.Value             = 0.1;
            app.beta0.Value         = 0.0025;
            app.verbosity.Value     = 1;
            app.TextArea_A.Value    = 'exp( -3*t )';
            app.TextArea_phi0.Value = '(N - S0) * A( t )';
            % set app object defaults
            app.AoI_Problem.N         = app.N.Value;
            app.AoI_Problem.S0        = app.S0.Value;
            app.AoI_Problem.beta      = app.beta0.Value;
            app.AoI_Problem.T         = app.T.Value;
            app.AoI_Problem.h         = app.h.Value;
            app.AoI_Problem.verbosity = app.verbosity.Value;
            app.AoI_Problem.A         = @(t)( exp( -3*t ) );
            app.AoI_Problem.phi0      = @(t)( (app.AoI_Problem.N - app.AoI_Problem.S0) ...
                                              * app.AoI_Problem.A( t ) );            
        end
        
        function funcString = mapFunctionArguments(app, funcString)
            % Private function to check wether the new string of functions A(t) or phi0(t)
            % contains references to AoI_Problem specific parameters, or not.
            % In the case, add the prefix 'app.AoI_Problem.' to the parameter name, so
            % that the function handle can find the corresponding value and
            % complete the function computation
            funcString = funcString{1}; % it is given as a single-element cell array
            for str = app.modelParNames
                paramRegEx = [app.lookbehind, str{1}, app.lookahead];
                strInd     = regexp( funcString, paramRegEx );
                if ( ~isempty(strInd) ) % do not use 'contains' here!
                    % prepend the prefix to all occurrence of the given
                    % parameter name in the function expression
                    for k = 1 : numel(strInd)
                        insertInd  = strInd(k) + (k-1) * app.prefixLen;
                        % Do not use 'strcat' here! It could remove meaningful white spaces
                        funcString = [ funcString(1 : insertInd-1), ...
                                       app.prefix,...
                                       funcString(insertInd : end) ];
                    end
                end
            end        
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setDefaults( app );
            rePlotSimulationData( app );
        end

        % Value changed function: TextArea_A
        function TextArea_AValueChanged(app, event)
            if ( isempty( app.TextArea_A.Value{:} ) )
                % EmptyAFieldException = ...
                %     MException('A1', 'The A(t) function cannot be empty.');
                app.Label_EndOfRunMessage.Text = ...
                               ['WARNING: the phi0(t) function cannot be empty.', ...
                                ' Reverting to default: exp( -3*t ).' ];
                app.errorFlag = 2;
                app.AoI_Problem.phi0 = @(t)( exp( -3 * t ) );
                app.TextArea_phi0.Value = 'exp( -3 * t )';
                % throw(EmptyAFieldException);
            else
                % possibly map model parameter names to the corresponding local variables
                funcString = mapFunctionArguments(app, app.TextArea_A.Value);
                % create the function handle and store it in the local AoI_Problem structure
                tempA = str2func( ['@(t, app)( ', funcString, ' )'] );
                app.AoI_Problem.A = @(t)( tempA(t, app) );
            end
            if ( app.errorFlag ), app.errorFlag = 0; end
            % if ( app.warningFlag ), app.warningFlag = 0; end
            resetRunButtonAndMsgLabel(app);
            rePlotSimulationData(app);
        end

        % Value changed function: TextArea_phi0
        function TextArea_phi0ValueChanged(app, event)
            if ( isempty( app.TextArea_phi0.Value{:} ) )
                % EmptyPhi0FieldException = ...
                %     MException('phi01', ...
                %                strcat('The $\phi_0$(t) function cannot be empty. ', ...
                %                       'Revert to default: (N - S0)*A(t).') );
                app.Label_EndOfRunMessage.Text = ...
                               ['WARNING: the phi0(t) function cannot be empty.', ...
                                ' Reverting to default: (N - S0)*A(t).' ];
                app.errorFlag = 1;
                app.AoI_Problem.phi0 = @(t)( (app.AoI_Problem.N - app.AoI_Problem.S0) ...
                                              * app.AoI_Problem.A( t ) );
                app.TextArea_phi0.Value = '(N - S0) * A( t )';
                % throw(EmptyPhi0FieldException);
            else
                % possibly map model parameter names to the corresponding local variables
                funcString = mapFunctionArguments(app, app.TextArea_phi0.Value);
                % create the function handle and store it in the local AoI_Problem structure
                tempPhi0 = str2func( ['@(t, app)( ', funcString, ' )'] );
                app.AoI_Problem.phi0 = @(t)( tempPhi0(t, app) );
            end
            if ( app.errorFlag ), app.errorFlag = 0; end
            % if ( app.warningFlag ), app.warningFlag = 0; end
            resetRunButtonAndMsgLabel(app);
            rePlotSimulationData(app);
        end

        % Value changed function: N
        function NValueChanged(app, event)
            app.AoI_Problem.N = app.N.Value;
            if ( app.errorFlag ), app.errorFlag = 0; end
            % if ( app.warningFlag ), app.warningFlag = 0; end
            resetRunButtonAndMsgLabel(app);
        end

        % Value changed function: S0
        function S0ValueChanged(app, event)
            app.AoI_Problem.S0 = app.S0.Value;
            if ( app.errorFlag ), app.errorFlag = 0; end
            % if ( app.warningFlag ), app.warningFlag = 0; end
            resetRunButtonAndMsgLabel(app);
        end

        % Value changed function: beta0
        function beta0ValueChanged(app, event)
            app.AoI_Problem.beta = app.beta0.Value;
            if ( app.errorFlag ), app.errorFlag = 0; end
            % if ( app.warningFlag ), app.warningFlag = 0; end
            resetRunButtonAndMsgLabel(app);
        end

        % Value changed function: T
        function TValueChanged(app, event)
            app.AoI_Problem.T = app.T.Value;
            if ( app.errorFlag ), app.errorFlag = 0; end
            % if ( app.warningFlag ), app.warningFlag = 0; end
            resetRunButtonAndMsgLabel(app);
            rePlotSimulationData(app);
        end

        % Value changed function: h
        function hValueChanged(app, event)
            app.AoI_Problem.h = app.h.Value;
            if ( app.errorFlag ), app.errorFlag = 0; end
            % if ( app.warningFlag ), app.warningFlag = 0; end
            resetRunButtonAndMsgLabel(app);
            rePlotSimulationData(app);            
        end

        % Value changed function: verbosity
        function verbosityValueChanged(app, event)
            app.AoI_Problem.verbosity = app.verbosity.Value;
            if ( app.errorFlag ), app.errorFlag = 0; end
            % if ( app.warningFlag ), app.warningFlag = 0; end
            resetRunButtonAndMsgLabel(app);
        end

        % Button pushed function: Button_Reset
        function Button_ResetPushed(app, event)
            setDefaults( app );
            startupFcn( app );
            resetRunButtonAndMsgLabel( app );
            delete( findobj(app.Axes_MeanInfectivity, 'type', 'line') );
            delete( findobj(app.Axes_Susceptibles, 'type', 'line') );
        end

        % Button pushed function: Button_RunSimulation
        function Button_RunSimulationPushed(app, event)
            try
                app.Button_RunSimulation.BackgroundColor = app.runningBgColor;
                app.Button_RunSimulation.Text = 'Computing...';

                [app.t_AoI, app.Y_AoI, app.P_AoI] = NSFD_AoI( app.AoI_Problem );

                EndOfRunMessage = [ 'The simulation has been successfully completed', ...
                                    ' and the results have been stored', ...
                                    ' in the variables t_AoI and Y_AoI.', ...
                                    newline, ...
                                    'A summary of the performance indicators is', ...
                                    ' available in the variable P_AoI.'];
                assignin('base', 't_AoI', app.t_AoI);
                assignin('base', 'Y_AoI', app.Y_AoI);
                assignin('base', 'P_AoI', app.P_AoI);
                app.Label_EndOfRunMessage.Text = EndOfRunMessage;
                plotSolution(app);
                app.Button_RunSimulation.BackgroundColor = app.waitingBgColor;
                app.Button_RunSimulation.Text = 'Run simulation';
            catch myException
                if ( ~isempty( strfind(myException.identifier,'warn') ) )
                    app.errorFlag = 1;
                    app.Button_RunSimulation.BackgroundColor = app.warningBgColor;
                    app.Button_RunSimulation.Text = '> Warning <';
                else
                    app.errorFlag = 2;
                    app.Button_RunSimulation.BackgroundColor = app.errorBgColor;
                    app.Button_RunSimulation.Text = '>> Error <<';
                end
                app.Label_EndOfRunMessage.Text = myException.message;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'SimulationTool';
            app.Axes_MeanInfectivity = uiaxes(app.UIFigure);
            title(app.Axes_MeanInfectivity, 'Mean infectivity')
            xlabel(app.Axes_MeanInfectivity, 't [days]')
            ylabel(app.Axes_MeanInfectivity, 'φ(t)')
            zlabel(app.Axes_MeanInfectivity, 'Z')
            app.Axes_Susceptibles = uiaxes(app.UIFigure);
            title(app.Axes_Susceptibles, 'Susceptibles')
            xlabel(app.Axes_Susceptibles, 't [days]')
            ylabel(app.Axes_Susceptibles, 'S(t)')
            zlabel(app.Axes_Susceptibles, 'Z')
            app.Axes_phi0 = uiaxes(app.UIFigure);
            title(app.Axes_phi0, 'φ_0(t)')
            xlabel(app.Axes_phi0, 't [days]')
            ylabel(app.Axes_phi0, 'φ_0(t)')
            zlabel(app.Axes_phi0, 'Z')
            app.Axes_InfectivityKernel = uiaxes(app.UIFigure);
            title(app.Axes_InfectivityKernel, 'Infectivity kernel')
            xlabel(app.Axes_InfectivityKernel, 't [days]')
            ylabel(app.Axes_InfectivityKernel, 'A(t)')
            zlabel(app.Axes_InfectivityKernel, 'Z')
            app.beta_0Label = uilabel(app.UIFigure);
            app.beta_0Label.Text = 'beta0 = ';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N = ';
            app.hEditFieldLabel = uilabel(app.UIFigure);
            app.hEditFieldLabel.Text = 'h = ';
            app.TEditFieldLabel = uilabel(app.UIFigure);
            app.TEditFieldLabel.Text = 'T = ';
            app.verbosityEditFieldLabel = uilabel(app.UIFigure);
            app.verbosityEditFieldLabel.Text = 'verbosity = ';
            app.AtLabel = uilabel(app.UIFigure);
            app.AtLabel.Text = 'A(t) = ';
            app.Label_SimulationResults = uilabel(app.UIFigure);
            app.Label_SimulationResults.Text = 'Simulation results';
            app.Label_SimulationsData = uilabel(app.UIFigure);
            app.Label_SimulationsData.Text = 'Simulation data';
            app.Label_EndOfRunMessage = uilabel(app.UIFigure);
            app.Label_EndOfRunMessage.Text = '';
            app.Button_Reset = uibutton(app.UIFigure, 'push');
            app.Button_Reset.ButtonPushedFcn = createCallbackFcn(app, @Button_ResetPushed, true);
            app.Button_Reset.Text = 'Reset';
            app.Button_RunSimulation = uibutton(app.UIFigure, 'push');
            app.Button_RunSimulation.ButtonPushedFcn = createCallbackFcn(app, @Button_RunSimulationPushed, true);
            app.Button_RunSimulation.Text = 'Run simulation';
            app.S0EditFieldLabel = uilabel(app.UIFigure);
            app.S0EditFieldLabel.Text = 'S0 = ';
            app.phi0tLabel = uilabel(app.UIFigure);
            app.phi0tLabel.Text = 'phi0(t) = ';
            app.TextArea_phi0 = uitextarea(app.UIFigure);
            app.TextArea_phi0.ValueChangedFcn = createCallbackFcn(app, @TextArea_phi0ValueChanged, true);
            app.TextArea_phi0.Value = {'(N - S0) * A( t )'};
            app.TextArea_A = uitextarea(app.UIFigure);
            app.TextArea_A.ValueChangedFcn = createCallbackFcn(app, @TextArea_AValueChanged, true);
            app.TextArea_A.Value = {'exp( -3*t )'};
            app.Label_InstructionsFunctionHandles = uilabel(app.UIFigure);
            app.Label_InstructionsFunctionHandles.Interpreter = 'latex';
            app.Label_InstructionsFunctionHandles.Text = {'{ Only ensure that $A(t)$ and $\varphi_{0}(t)$ are defined in vectorized} form, using component-wise operators and $t$ as the independent variable. For instance, write:'; '\texttt{t.*\exp(-t.^2)./(1+t.^3)}\quad  for the function    $\displaystyle\frac{\ t\cdot \mathrm{e}^{-t^2}\ }{\ 1 + t^3\ }$.'};
            app.Label_FunctionHandlesSection = uilabel(app.UIFigure);
            app.Label_FunctionHandlesSection.Text = 'Function handle section';
            app.Label_verbosity = uilabel(app.UIFigure);
            app.Label_verbosity.Text = '0 or 1';
            app.verbosity = uieditfield(app.UIFigure, 'numeric');
            app.verbosity.Limits = [0 1];
            app.verbosity.ValueDisplayFormat = '%2d';
            app.verbosity.ValueChangedFcn = createCallbackFcn(app, @verbosityValueChanged, true);
            app.verbosity.Value = 1;
            app.Label_beta0 = uilabel(app.UIFigure);
            app.Label_beta0.Text = '> 0';
            app.beta0 = uieditfield(app.UIFigure, 'numeric');
            app.beta0.LowerLimitInclusive = 'off';
            app.beta0.UpperLimitInclusive = 'off';
            app.beta0.Limits = [0 Inf];
            app.beta0.ValueChangedFcn = createCallbackFcn(app, @beta0ValueChanged, true);
            app.beta0.Value = 0.0025;
            app.Label_h = uilabel(app.UIFigure);
            app.Label_h.Text = '> 0';
            app.h = uieditfield(app.UIFigure, 'numeric');
            app.h.LowerLimitInclusive = 'off';
            app.h.UpperLimitInclusive = 'off';
            app.h.Limits = [0 Inf];
            app.h.ValueChangedFcn = createCallbackFcn(app, @hValueChanged, true);
            app.h.Value = 0.1;
            app.Label_T = uilabel(app.UIFigure);
            app.Label_T.Text = '> 0';
            app.T = uieditfield(app.UIFigure, 'numeric');
            app.T.LowerLimitInclusive = 'off';
            app.T.UpperLimitInclusive = 'off';
            app.T.Limits = [0 Inf];
            app.T.ValueChangedFcn = createCallbackFcn(app, @TValueChanged, true);
            app.T.Value = 10;
            app.Label_S0 = uilabel(app.UIFigure);
            app.Label_S0.Text = '> 0';
            app.S0 = uieditfield(app.UIFigure, 'numeric');
            app.S0.LowerLimitInclusive = 'off';
            app.S0.UpperLimitInclusive = 'off';
            app.S0.Limits = [0 Inf];
            app.S0.RoundFractionalValues = 'on';
            app.S0.ValueChangedFcn = createCallbackFcn(app, @S0ValueChanged, true);
            app.S0.Value = 990;
            app.Label_N = uilabel(app.UIFigure);
            app.Label_N.Text = '> 0 (integer)';
            app.N = uieditfield(app.UIFigure, 'numeric');
            app.N.LowerLimitInclusive = 'off';
            app.N.UpperLimitInclusive = 'off';
            app.N.Limits = [0 Inf];
            app.N.RoundFractionalValues = 'on';
            app.N.ValueDisplayFormat = '%d';
            app.N.ValueChangedFcn = createCallbackFcn(app, @NValueChanged, true);
            app.N.Value = 1000;
            app.Label_ScalarParametersSection = uilabel(app.UIFigure);
            app.Label_ScalarParametersSection.Text = 'Scalar parameters section';
            app.Label_FootLine = uilabel(app.UIFigure);
            app.Label_FootLine.Text = 'Credits: M. Pezzella (IAC-CNR, Naples, Italy) - C. Panico, E. Messina, B. Buonomo (Univ. Federico II, Naples, Italy) - G. Zanghirati (Univ. of Ferrara, Italy)';
            app.Label_Title = uilabel(app.UIFigure);
            app.Label_Title.Text = {'EPITIME - Simulation tool'; 'Unconditionally Positive Non Standard Finite Difference (NSFD) scheme'; 'for the solution of the Age-of-Infection (AoI) model'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EPITIME_SimulationTool

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end